classdef UVChlorineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PlotsTabGroup                   matlab.ui.container.TabGroup
        TabOxidants                     matlab.ui.container.Tab
        Ax3                             matlab.ui.control.UIAxes
        Ax2                             matlab.ui.control.UIAxes
        Ax1                             matlab.ui.control.UIAxes
        TabTargets                      matlab.ui.container.Tab
        Ax6                             matlab.ui.control.UIAxes
        Ax5                             matlab.ui.control.UIAxes
        Ax4                             matlab.ui.control.UIAxes
        TabMatrixOptics                 matlab.ui.container.Tab
        Ax9                             matlab.ui.control.UIAxes
        Ax8                             matlab.ui.control.UIAxes
        Ax7                             matlab.ui.control.UIAxes
        InputsPanel                     matlab.ui.container.Panel
        ResultsTable                    matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        ExperimentaloverlayPanel        matlab.ui.container.Panel
        ExcelSheetName                  matlab.ui.control.EditField
        ExcelsheetnameEditFieldLabel    matlab.ui.control.Label
        OverlayexperimentaldataLabel    matlab.ui.control.Label
        UseExcelCheckBox                matlab.ui.control.CheckBox
        TargetsinitialmolLPanel         matlab.ui.container.Panel
        Sucralose_M                     matlab.ui.control.NumericEditField
        SucraloseMEditFieldLabel        matlab.ui.control.Label
        Caffeine_M                      matlab.ui.control.NumericEditField
        CaffeineMEditFieldLabel         matlab.ui.control.Label
        Dioxane_M                       matlab.ui.control.NumericEditField
        DioxaneMEditFieldLabel          matlab.ui.control.Label
        WatermatrixPanel                matlab.ui.container.Panel
        Scavenging_s_inv                matlab.ui.control.NumericEditField
        OHbackgroundscavengingsEditFieldLabel  matlab.ui.control.Label
        Transmittance                   matlab.ui.control.NumericEditField
        Backgroundtransmittance01EditFieldLabel  matlab.ui.control.Label
        Phosphate_mM                    matlab.ui.control.NumericEditField
        PhosphatemMEditFieldLabel       matlab.ui.control.Label
        Chloride_mgL                    matlab.ui.control.NumericEditField
        ClmgLEditFieldLabel             matlab.ui.control.Label
        DOC_mgC_L                       matlab.ui.control.NumericEditField
        DOCmgCLEditFieldLabel           matlab.ui.control.Label
        Alkalinity_mgL_as_CaCO3         matlab.ui.control.NumericEditField
        AlkalinitymgLasCaCOEditFieldLabel  matlab.ui.control.Label
        NH3_mgN_L                       matlab.ui.control.NumericEditField
        NHmgNLLabel                     matlab.ui.control.Label
        pH                              matlab.ui.control.NumericEditField
        pHEditFieldLabel                matlab.ui.control.Label
        OxidantsmgLPanel                matlab.ui.container.Panel
        H2O2_mgL                        matlab.ui.control.NumericEditField
        HOmgLLabel                      matlab.ui.control.Label
        NCl3_mgL                        matlab.ui.control.NumericEditField
        NClmgClLEditFieldLabel          matlab.ui.control.Label
        NHCl2_mgL                       matlab.ui.control.NumericEditField
        NHClmgClLEditFieldLabel_2       matlab.ui.control.Label
        NH2Cl_mgL                       matlab.ui.control.NumericEditField
        NHClmgClLEditFieldLabel         matlab.ui.control.Label
        Cl2_mgL                         matlab.ui.control.NumericEditField
        ClmgClLEditFieldLabel           matlab.ui.control.Label
        SimulationUVsourcePanel         matlab.ui.container.Panel
        Area_m2                         matlab.ui.control.NumericEditField
        IlluminatedareamEditFieldLabel  matlab.ui.control.Label
        Volume_L                        matlab.ui.control.NumericEditField
        IlluminatedvolumeLEditFieldLabel  matlab.ui.control.Label
        OpticalPath_cm                  matlab.ui.control.NumericEditField
        OpticalpathLcmEditFieldLabel    matlab.ui.control.Label
        Lamp_mWcm2                      matlab.ui.control.NumericEditField
        UVirradiancemWcmEditFieldLabel  matlab.ui.control.Label
        TemperatureC                    matlab.ui.control.NumericEditField
        TempCEditFieldLabel             matlab.ui.control.Label
        TSpanEnd                        matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel           matlab.ui.control.Label
        TSpanStep                       matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        TSpanStart                      matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel         matlab.ui.control.Label
        TimeUnitDropDown                matlab.ui.control.DropDown
        TimeunitDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        t               % time vector
        y               % state matrix
        appStruct       % parameters struct passed to ODE and plotting
        t_unitFactor    % struct with .sec and .min % Description
    end
    
    methods (Access = private)
        function y0 = buildInitialStateFromInputs(app)
            a = app.appStruct;
            y0 = zeros(63,1);
    
            % Carbonate speciation
            H = 10^(-a.pH);
            TIC_molesL = a.alkalinity*1.22/61000;
            K1 = 10^-6.77; K2 = 10^-9.93;
            Den = H*H + H*K1 + K1*K2;
            H2CO3 = TIC_molesL*H*H/Den;
            HCO3  = TIC_molesL*H*K1/Den;
            CO3   = TIC_molesL*K1*K2/Den;
    
            % Phosphate speciation
            Phosphate_moles = a.phosphate/1000;
            Ka1=10^(-2.1); Ka2=10^(-7.2); Ka3=10^(-12.3);
            H = 10^(-a.pH);
            D = 1 + (Ka1/H) + (Ka1*Ka2)/(H^2) + (Ka1*Ka2*Ka3)/(H^3);
            % H3PO4  = Phosphate_moles / D;
            H2PO4  = Phosphate_moles * (Ka1/H) / D;
            HPO4   = Phosphate_moles * (Ka1*Ka2)/(H^2) / D;
            % PO4    = Phosphate_moles * (Ka1*Ka2*Ka3)/(H^3) / D;
    
            % Map inputs to y0 (as in run.m)
            y0(1)  = a.NH2Cl_mgL / 71000;
            y0(2)  = a.NHCl2_mgL / (2 * 71000);
            y0(3)  = a.Cl2_mgL/(1+10^(a.pH-7.52))/(71*1000);
            y0(4)  = a.Cl2_mgL/(71 * 1000) - y0(3);
    
            y0(8)  = a.NCl3_mgL / (3 * 71000);
            y0(9)  = 0;
            y0(10) = a.NH3_mgL / 14000;
    
            y0(11) = a.H2O2_mgL/(34 * 1000);
    
            y0(34) = a.dioxane;
            y0(35) = 0;          % DOC
            y0(36) = 0;          % O2
    
            y0(37) = 10^(-a.pH);
            y0(38) = 10^(-14)/y0(37);
    
            y0(39) = HCO3;  y0(40) = H2CO3; y0(41) = CO3;
    
            y0(42) = a.chloride / (35.5 * 1000);
    
            y0(51) = H2PO4;
            y0(50) = HPO4;
    
            y0(61) = a.caffeine;
            y0(62) = a.sucralose;
            y0(63) = 0;
        end
        
        function [time_diox, time_carb, time_sucr] = computeHalfLogTimes(app, t, y, y0)
            thr_diox = y0(34) * 10^(-0.5);
            thr_carb = y0(61) * 10^(-0.5);
            thr_sucr = y0(62) * 10^(-0.5);
            time_diox = NaN; time_carb = NaN; time_sucr = NaN;

            for i=1:length(t)-1
                if (y(i,34) > thr_diox && y(i+1,34) <= thr_diox) || (y(i,34) < thr_diox && y(i+1,34) >= thr_diox)
                    time_diox = interp1([y(i,34), y(i+1,34)], [t(i), t(i+1)], thr_diox); break;
                end
            end
            for i=1:length(t)-1
                if (y(i,61) > thr_carb && y(i+1,61) <= thr_carb) || (y(i,61) < thr_carb && y(i+1,61) >= thr_carb)
                    time_carb = interp1([y(i,61), y(i+1,61)], [t(i), t(i+1)], thr_carb); break;
                end
            end
            for i=1:length(t)-1
                if (y(i,62) > thr_sucr && y(i+1,62) <= thr_sucr) || (y(i,62) < thr_sucr && y(i+1,62) >= thr_sucr)
                    time_sucr = interp1([y(i,62), y(i+1,62)], [t(i), t(i+1)], thr_sucr); break;
                end
            end
        end
        
        function exp = getExperimentalData(app)
            exp = struct('ok',false);
            try
                T = readtable('UVCl2 photolysis.xlsx', ...
                    'Sheet', app.ExcelSheetName.Value, ...
                    'VariableNamingRule','preserve');

                exp.ok = true;
                exp.t        = T.('time(minute)');
                exp.freeCl   = T.('Cl2free (mgCl2/L)');
                exp.totalCl  = T.('Cl2total (mgCl2/L)');
                exp.dioxane  = T.('dioxane C/C0');
                exp.sucralose= T.('sucralose C/C0');
                exp.caffeine = T.('caffeine C/C0');
                exp.pH       = T.('pH');
            catch ME
                warning('Experimental overlay disabled: %s', ME.message);
                exp.ok = false;
            end
        end

        
        function plotInApp(app, t, y, y0)
            a = app.appStruct;
            labs = ['Time (' a.t_unit ')'];
            xlabel(app.Ax1, labs); xlabel(app.Ax2, labs); xlabel(app.Ax3, labs);
            xlabel(app.Ax4, labs); xlabel(app.Ax5, labs); xlabel(app.Ax6, labs);
            xlabel(app.Ax7, labs); xlabel(app.Ax8, labs); xlabel(app.Ax9, labs);

            % Experimental overlay?
            exp = struct('ok',false);
            if app.UseExcelCheckBox.Value
                exp = getExperimentalData(app);
            end

            % Ax1: Oxidants
            ax = app.Ax1; cla(ax); hold(ax,'on');
            MM_Cl2 = 71000; MM_H2O2 = 34000;
            plot(ax, t, y(:,3) * MM_Cl2, 'k', 'LineWidth',1.2);        % HOCl
            plot(ax, t, y(:,4) * MM_Cl2, 'r', 'LineWidth',1.2);        % OCl-
            plot(ax, t, y(:,1) * MM_Cl2, 'g', 'LineWidth',1.2);        % NH2Cl
            plot(ax, t, y(:,2) * 2 * MM_Cl2, 'b', 'LineWidth',1.2);    % NHCl2 (as Cl2)
            plot(ax, t, y(:,11)* MM_H2O2, 'y', 'LineWidth',1.2);       % H2O2
            plot(ax, t, ((y(:,3)+y(:,4))*MM_Cl2 + y(:,1)*MM_Cl2 + y(:,2)*2*MM_Cl2), '--', 'Color',[0 1 1],'LineWidth',1.5);
            plot(ax, t, ((y(:,3)+y(:,4))*MM_Cl2), '--', 'Color',[1 0 0],'LineWidth',1.5);
            if exp.ok
                plot(ax, exp.t, exp.freeCl,  'o','MarkerFaceColor','r','MarkerEdgeColor','r','LineWidth',1.5);
                plot(ax, exp.t, exp.totalCl, 'o','MarkerFaceColor','c','MarkerEdgeColor','c','LineWidth',1.5);
            end
            legend(ax, {'HOCl','OCl^-','NH_2Cl','NHCl_2','H_2O_2','Total Cl (model)','Free Cl (model)','Exp Free Cl','Exp Total Cl'}, 'Location','best');
            ylabel(ax,'Concentration (mg/L)'); hold(ax,'off');

            % Ax2: Ammonia (as N)
            ax = app.Ax2; cla(ax); hold(ax,'on');
            MM_N = 14000;
            ammonia_mgN_L = y(:,10) * MM_N;
            plot(ax, t, ammonia_mgN_L, 'b', 'LineWidth',1.2);
            ylabel(ax,'mg N / L'); legend(ax,'NH_3 / NH_4^+','Location','best'); ylim(ax,[0 1.1]); hold(ax,'off');
        
            % Ax3: pH
            ax = app.Ax3; cla(ax);
            pH = -log10(y(:,37));
            plot(ax, t, pH, 'b');
            ylabel(ax,'pH'); ylim(ax,[3 11]);
        
            % Ax4: Targets C/C0
            ax = app.Ax4; cla(ax); hold(ax,'on');
            log_line = 10^(-0.5);
            plot(ax, t, y(:,34)/y(1,34), 'b', 'DisplayName','Model 1,4-Dioxane');
            plot(ax, t, y(:,61)/y(1,61), 'g', 'DisplayName','Model Caffeine');
            plot(ax, t, y(:,62)/y(1,62), 'r', 'DisplayName','Model Sucralose');
            plot(ax, t, ones(size(t))*log_line, '--', 'DisplayName','0.5-log reduction');
            if exp.ok
                plot(ax, exp.t, exp.dioxane,   'o','MarkerEdgeColor','b','MarkerFaceColor','b','DisplayName','Exp Dioxane');
                plot(ax, exp.t, exp.caffeine,  's','MarkerEdgeColor','g','MarkerFaceColor','g','DisplayName','Exp Caffeine');
                plot(ax, exp.t, exp.sucralose, '^','MarkerEdgeColor','r','MarkerFaceColor','r','DisplayName','Exp Sucralose');
            end
            ylabel(ax,'C/C_0'); legend(ax,'show','Location','best'); hold(ax,'off');
        
            % Ax5: OH exposure
            ax = app.Ax5; cla(ax);
            plot(ax, t, y(:,63), 'k');
            legend(ax,'*OH exposure','Location','best');
            ylabel(ax,'mol/L');
        
            % Ax6: Carbonate
            ax = app.Ax6; cla(ax); hold(ax,'on');
            plot(ax, t, y(:,40), 'k');  % H2CO3
            plot(ax, t, y(:,39), 'r');  % HCO3-
            plot(ax, t, y(:,41), 'b');  % CO3^2-
            legend(ax,'H_2CO_3','HCO_3^-','CO_3^{2-}','Location','best');
            ylabel(ax,'mol/L'); hold(ax,'off');
        
            % Ax7: Phosphate
            ax = app.Ax7; cla(ax); hold(ax,'on');
            plot(ax, t, y(:,50), 'm');  % HPO4^2-
            plot(ax, t, y(:,51), 'c');  % H2PO4^-
            legend(ax,'HPO_4^{2-}','H_2PO_4^-','Location','best');
            ylabel(ax,'mol/L'); hold(ax,'off');
        
            % Ax8: UVT
            ax = app.Ax8; cla(ax);
            total_absorbance = 62*y(:,3) + 66*y(:,4) + 371*y(:,1) + ...
                               142*y(:,2) + 18*y(:,11) + 228*y(:,13) + a.a_background;
            UVT = 10.^(-total_absorbance) * 100;
            plot(ax, t, UVT, 'k');
            legend(ax,'UVT','Location','best'); ylabel(ax,'UVT (%)');
        
            % Ax9: Total absorbance
            ax = app.Ax9; cla(ax);
            plot(ax, t, total_absorbance, 'k');
            legend(ax,'Total Absorbance','Location','best'); ylabel(ax,'Absorbance');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Time unit conversion factors
            app.t_unitFactor.sec = 1;
            app.t_unitFactor.min = 60;

            % Pre-fill the results table
            app.ResultsTable.Data = {
                '1,4-Dioxane', NaN;
                'Caffeine',    NaN;
                'Sucralose',   NaN;
            };

            % Put axis labels so tabs aren’t blank at launch
            xlabel(app.Ax1, 'Time'); ylabel(app.Ax1, 'Concentration (mg/L)');
            xlabel(app.Ax2, 'Time'); ylabel(app.Ax2, 'mg N / L');
            xlabel(app.Ax3, 'Time'); ylabel(app.Ax3, 'pH');
            xlabel(app.Ax4, 'Time'); ylabel(app.Ax4, 'C/C_0');
            xlabel(app.Ax5, 'Time'); ylabel(app.Ax5, '\int [\cdotOH] dt');
            xlabel(app.Ax6, 'Time'); ylabel(app.Ax6, 'mol/L');
            xlabel(app.Ax7, 'Time'); ylabel(app.Ax7, 'mol/L');
            xlabel(app.Ax8, 'Time'); ylabel(app.Ax8, 'UVT (%)');
            xlabel(app.Ax9, 'Time'); ylabel(app.Ax9, 'Absorbance');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % ---- 1) Collect inputs from the UI ----
            a = struct;

            a.t_unit = app.TimeUnitDropDown.Value;
            t0   = app.TSpanStart.Value;
            dt   = app.TSpanStep.Value;
            tend = app.TSpanEnd.Value;
            tspan = t0:dt:tend;

            a.T_Celcius = app.TemperatureC.Value;
            a.T = a.T_Celcius + 273.15;

            a.E0_mWcm2 = app.Lamp_mWcm2.Value;
            % keep your original run.m logic (no fixes)
            a.E0_Wm2 = a.E0_mWcm2 * 1;
            a.E0 = a.E0_Wm2 * (254e-9 / (6.626e-34 * 3e8 * 6.022e23));

            a.L = app.OpticalPath_cm.Value;  % cm
            a.V = app.Volume_L.Value;        % L
            a.A = app.Area_m2.Value;         % m^2

            % Oxidants (mg/L)
            a.Cl2_mgL   = app.Cl2_mgL.Value;
            a.NH2Cl_mgL = app.NH2Cl_mgL.Value;
            a.NHCl2_mgL = app.NHCl2_mgL.Value;
            a.H2O2_mgL  = app.H2O2_mgL.Value;
            a.NCl3_mgL  = app.NCl3_mgL.Value;

            % Water matrix
            a.NH3_mgL    = app.NH3_mgN_L.Value;
            a.pH         = app.pH.Value;
            a.sc         = app.Scavenging_s_inv.Value;
            a.alkalinity = app.Alkalinity_mgL_as_CaCO3.Value;
            a.DOC        = app.DOC_mgC_L.Value;
            a.chloride   = app.Chloride_mgL.Value;
            a.phosphate  = app.Phosphate_mM.Value;

            % Background absorbance from transmittance
            a.transmittance = app.Transmittance.Value; % 0..1
            a.a_background  = -log10(a.transmittance);

            % Targets (mol/L)
            a.dioxane   = app.Dioxane_M.Value;
            a.caffeine  = app.Caffeine_M.Value;
            a.sucralose = app.Sucralose_M.Value;

            app.appStruct = a;

            % ---- 2) Initial state (same as run.m) ----
            y0 = buildInitialStateFromInputs(app);

            % ---- 3) Solve ODE with your merged_ODE.m ----
            opts = odeset('RelTol',1e-8,'AbsTol',1e-15,'NonNegative',1:63,'MaxStep',1e-2);
            [t, y] = ode15s(@(tt,yy) merged_ODE(tt, yy, app.t_unitFactor, app.appStruct), ...
                    tspan, y0, opts);
            app.t = t; app.y = y;

            % ---- 4) 0.5-log times ----
            [tdiox, tcarb, tsucr] = computeHalfLogTimes(app, t, y, y0);
            app.ResultsTable.Data = {
                '1,4-Dioxane', tdiox;
                'Caffeine',    tcarb;
                'Sucralose',   tsucr;
            };

            % ---- 5) Draw into the tabbed axes ----
            plotInApp(app, t, y, y0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputsPanel = uipanel(app.GridLayout);
            app.InputsPanel.Title = 'Inputs';
            app.SimulationUVsourcePanel = uipanel(app.InputsPanel);
            app.SimulationUVsourcePanel.Title = 'Simulation & UV source';
            app.TimeunitDropDownLabel = uilabel(app.SimulationUVsourcePanel);
            app.TimeunitDropDownLabel.Text = 'Time unit';
            app.TimeUnitDropDown = uidropdown(app.SimulationUVsourcePanel);
            app.TimeUnitDropDown.Items = {'sec', 'min', ''};
            app.TimeUnitDropDown.Value = 'min';
            app.StarttimeEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.TSpanStart = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.TimestepEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TSpanStep = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.TSpanStep.Value = 0.01;
            app.EndtimeEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.TSpanEnd = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.TSpanEnd.Value = 5;
            app.TempCEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.TempCEditFieldLabel.Text = 'Temp (°C)';
            app.TemperatureC = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.TemperatureC.Value = 20;
            app.UVirradiancemWcmEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.UVirradiancemWcmEditFieldLabel.Text = 'UV irradiance (mW/cm²)';
            app.Lamp_mWcm2 = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.Lamp_mWcm2.Value = 10;
            app.OpticalpathLcmEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.OpticalpathLcmEditFieldLabel.Text = 'Optical path L (cm)';
            app.OpticalPath_cm = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.OpticalPath_cm.Value = 3.5;
            app.IlluminatedvolumeLEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.IlluminatedvolumeLEditFieldLabel.Text = 'Illuminated volume (L)';
            app.Volume_L = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.Volume_L.Value = 0.0528;
            app.IlluminatedareamEditFieldLabel = uilabel(app.SimulationUVsourcePanel);
            app.IlluminatedareamEditFieldLabel.Text = 'Illuminated area (m²)';
            app.Area_m2 = uieditfield(app.SimulationUVsourcePanel, 'numeric');
            app.Area_m2.Value = 0.002642;
            app.OxidantsmgLPanel = uipanel(app.InputsPanel);
            app.OxidantsmgLPanel.Title = 'Oxidants (mg/L)';
            app.ClmgClLEditFieldLabel = uilabel(app.OxidantsmgLPanel);
            app.ClmgClLEditFieldLabel.Text = 'Cl₂ (mg Cl₂/L)';
            app.Cl2_mgL = uieditfield(app.OxidantsmgLPanel, 'numeric');
            app.Cl2_mgL.Value = 5;
            app.NHClmgClLEditFieldLabel = uilabel(app.OxidantsmgLPanel);
            app.NHClmgClLEditFieldLabel.Text = 'NH₂Cl (mg Cl₂/L)';
            app.NH2Cl_mgL = uieditfield(app.OxidantsmgLPanel, 'numeric');
            app.NHClmgClLEditFieldLabel_2 = uilabel(app.OxidantsmgLPanel);
            app.NHClmgClLEditFieldLabel_2.Text = 'NHCl₂ (mg Cl₂/L)';
            app.NHCl2_mgL = uieditfield(app.OxidantsmgLPanel, 'numeric');
            app.NClmgClLEditFieldLabel = uilabel(app.OxidantsmgLPanel);
            app.NClmgClLEditFieldLabel.Text = 'NCl₃ (mg Cl₂/L)';
            app.NCl3_mgL = uieditfield(app.OxidantsmgLPanel, 'numeric');
            app.HOmgLLabel = uilabel(app.OxidantsmgLPanel);
            app.HOmgLLabel.Text = 'H₂O₂ (mg/L)';
            app.H2O2_mgL = uieditfield(app.OxidantsmgLPanel, 'numeric');
            app.WatermatrixPanel = uipanel(app.InputsPanel);
            app.WatermatrixPanel.Title = 'Water matrix';
            app.pHEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pH = uieditfield(app.WatermatrixPanel, 'numeric');
            app.pH.Value = 5.5;
            app.NHmgNLLabel = uilabel(app.WatermatrixPanel);
            app.NHmgNLLabel.Text = 'NH₃ (mg N/L)';
            app.NH3_mgN_L = uieditfield(app.WatermatrixPanel, 'numeric');
            app.AlkalinitymgLasCaCOEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.AlkalinitymgLasCaCOEditFieldLabel.Text = 'Alkalinity (mg/L as CaCO₃)';
            app.Alkalinity_mgL_as_CaCO3 = uieditfield(app.WatermatrixPanel, 'numeric');
            app.DOCmgCLEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.DOCmgCLEditFieldLabel.Text = 'DOC (mg C/L)';
            app.DOC_mgC_L = uieditfield(app.WatermatrixPanel, 'numeric');
            app.ClmgLEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.ClmgLEditFieldLabel.Text = 'Cl⁻ (mg/L)';
            app.Chloride_mgL = uieditfield(app.WatermatrixPanel, 'numeric');
            app.PhosphatemMEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.PhosphatemMEditFieldLabel.Text = 'Phosphate (mM)';
            app.Phosphate_mM = uieditfield(app.WatermatrixPanel, 'numeric');
            app.Backgroundtransmittance01EditFieldLabel = uilabel(app.WatermatrixPanel);
            app.Backgroundtransmittance01EditFieldLabel.Text = 'Background transmittance (0–1)';
            app.Transmittance = uieditfield(app.WatermatrixPanel, 'numeric');
            app.Transmittance.Value = 1;
            app.OHbackgroundscavengingsEditFieldLabel = uilabel(app.WatermatrixPanel);
            app.OHbackgroundscavengingsEditFieldLabel.Text = 'OH background scavenging (s⁻¹)';
            app.Scavenging_s_inv = uieditfield(app.WatermatrixPanel, 'numeric');
            app.TargetsinitialmolLPanel = uipanel(app.InputsPanel);
            app.TargetsinitialmolLPanel.Title = 'Targets (initial mol/L)';
            app.DioxaneMEditFieldLabel = uilabel(app.TargetsinitialmolLPanel);
            app.DioxaneMEditFieldLabel.Text = '1,4-Dioxane (M)';
            app.Dioxane_M = uieditfield(app.TargetsinitialmolLPanel, 'numeric');
            app.CaffeineMEditFieldLabel = uilabel(app.TargetsinitialmolLPanel);
            app.CaffeineMEditFieldLabel.Text = 'Caffeine (M)';
            app.Caffeine_M = uieditfield(app.TargetsinitialmolLPanel, 'numeric');
            app.SucraloseMEditFieldLabel = uilabel(app.TargetsinitialmolLPanel);
            app.SucraloseMEditFieldLabel.Text = 'Sucralose (M';
            app.Sucralose_M = uieditfield(app.TargetsinitialmolLPanel, 'numeric');
            app.ExperimentaloverlayPanel = uipanel(app.InputsPanel);
            app.ExperimentaloverlayPanel.Title = 'Experimental overlay';
            app.UseExcelCheckBox = uicheckbox(app.ExperimentaloverlayPanel);
            app.UseExcelCheckBox.Text = 'True';
            app.UseExcelCheckBox.Value = true;
            app.OverlayexperimentaldataLabel = uilabel(app.ExperimentaloverlayPanel);
            app.OverlayexperimentaldataLabel.Text = 'Overlay experimental data';
            app.ExcelsheetnameEditFieldLabel = uilabel(app.ExperimentaloverlayPanel);
            app.ExcelsheetnameEditFieldLabel.Text = 'Excel sheet name';
            app.ExcelSheetName = uieditfield(app.ExperimentaloverlayPanel, 'text');
            app.ExcelSheetName.Value = 'Rayox 9,6mgL Cl2 pH 5';
            app.RunButton = uibutton(app.InputsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Simulation';
            app.ResultsTable = uitable(app.InputsPanel);
            app.ResultsTable.ColumnName = {'Species'; 'Time to 0.5-log (t_unit)'};
            app.ResultsTable.RowName = {};
            app.PlotsTabGroup = uitabgroup(app.GridLayout);
            app.TabOxidants = uitab(app.PlotsTabGroup);
            app.TabOxidants.Title = 'Oxidants & pH';
            app.Ax1 = uiaxes(app.TabOxidants);
            title(app.Ax1, 'Oxidant Concentrations (absolute)')
            xlabel(app.Ax1, 'X')
            ylabel(app.Ax1, 'Y')
            zlabel(app.Ax1, 'Z')
            app.Ax2 = uiaxes(app.TabOxidants);
            title(app.Ax2, 'Ammonia Concentration')
            xlabel(app.Ax2, 'X')
            ylabel(app.Ax2, 'Y')
            zlabel(app.Ax2, 'Z')
            app.Ax3 = uiaxes(app.TabOxidants);
            title(app.Ax3, 'pH Over Time')
            xlabel(app.Ax3, 'X')
            ylabel(app.Ax3, 'Y')
            zlabel(app.Ax3, 'Z')
            app.TabTargets = uitab(app.PlotsTabGroup);
            app.TabTargets.Title = 'Targets & •OH';
            app.Ax4 = uiaxes(app.TabTargets);
            title(app.Ax4, 'Target Micropollutant Concentration')
            xlabel(app.Ax4, 'X')
            ylabel(app.Ax4, 'Y')
            zlabel(app.Ax4, 'Z')
            app.Ax5 = uiaxes(app.TabTargets);
            title(app.Ax5, '[·OH] Exposure')
            xlabel(app.Ax5, 'X')
            ylabel(app.Ax5, 'Y')
            zlabel(app.Ax5, 'Z')
            app.Ax6 = uiaxes(app.TabTargets);
            title(app.Ax6, 'Carbonate Species Concentrations')
            xlabel(app.Ax6, 'X')
            ylabel(app.Ax6, 'Y')
            zlabel(app.Ax6, 'Z')
            app.TabMatrixOptics = uitab(app.PlotsTabGroup);
            app.TabMatrixOptics.Title = 'Phosphate & UV';
            app.Ax7 = uiaxes(app.TabMatrixOptics);
            title(app.Ax7, 'Phosphate Species Concentrations')
            xlabel(app.Ax7, 'X')
            ylabel(app.Ax7, 'Y')
            zlabel(app.Ax7, 'Z')
            app.Ax8 = uiaxes(app.TabMatrixOptics);
            title(app.Ax8, 'Total UV Transmittance')
            xlabel(app.Ax8, 'X')
            ylabel(app.Ax8, 'Y')
            zlabel(app.Ax8, 'Z')
            app.Ax9 = uiaxes(app.TabMatrixOptics);
            title(app.Ax9, 'Total Absorbance')
            xlabel(app.Ax9, 'X')
            ylabel(app.Ax9, 'Y')
            zlabel(app.Ax9, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UVChlorineApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end